classdef exp_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ProtocolTextArea         matlab.ui.control.TextArea
        WidefieldLamp            matlab.ui.control.Lamp
        WidefieldLampLabel       matlab.ui.control.Label
        ErrorsTextArea           matlab.ui.control.TextArea
        ErrorsTextAreaLabel      matlab.ui.control.Label
        TimeliteLamp             matlab.ui.control.Lamp
        TimeliteLampLabel        matlab.ui.control.Label
        MousecamLamp             matlab.ui.control.Lamp
        MousecamLampLabel        matlab.ui.control.Label
        StimLamp                 matlab.ui.control.Lamp
        StimLampLabel            matlab.ui.control.Label
        StartButton              matlab.ui.control.StateButton
        ChooseprotocolButton     matlab.ui.control.StateButton
        MousenameEditField       matlab.ui.control.EditField
        MousenameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mouse_name
        date
        time

        protocol_name
        protocol_path

        server_stim 
        server_timelite 
        server_mousecam 
        server_widefield

        connections_timer = 0;
    end
    
    methods (Access = public)
        function TimerCallback(app, obj, event)
            if app.server_stim.Connected==1
                app.StimLamp.Color = [0.00,1.00,0.00];
            else
                app.StimLamp.Color = [1.00, 0.00, 0.00];
            end

            if app.server_mousecam.Connected==1
                app.MousecamLamp.Color = [0.00,1.00,0.00];
            else
                app.MousecamLamp.Color = [1.00, 0.00, 0.00];
            end

            if app.server_timelite.Connected==1
                app.TimeliteLamp.Color = [0.00,1.00,0.00];
            else
                app.TimeliteLamp.Color = [1.00, 0.00, 0.00];
            end

            if app.server_widefield.Connected==1
                app.WidefieldLamp.Color = [0.00,1.00,0.00];
            else
                app.WidefieldLamp.Color = [1.00, 0.00, 0.00];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.server_stim = tcpserver("0.0.0.0",plab.locations.bonsai_port);
            app.server_timelite = tcpserver("0.0.0.0",plab.locations.timelite_port);
            app.server_mousecam = tcpserver("0.0.0.0",plab.locations.mousecam_port);
            app.server_widefield = tcpserver("0.0.0.0",plab.locations.widefield_port);
            configureCallback(app.server_stim, "terminator", @(src,event,x,y,z) plab.rig.exp_control_helpers.read_bonsai_end_exp(src,event, ...
                app.server_timelite,app.server_mousecam,app.server_widefield,app.StartButton));
            app.connections_timer = timer('Period', 1/10, 'ExecutionMode', 'fixedDelay', ...
                'TasksToExecute', inf);
            app.connections_timer.TimerFcn = @app.TimerCallback;
            start(app.connections_timer);
        end

        % Value changed function: ChooseprotocolButton
        function ChooseprotocolButtonValueChanged(app, event)
            [app.protocol_name, full_protocol_path] = uigetfile(fullfile(plab.locations.local_workflow_path, '*.bonsai'));
            app.protocol_path = full_protocol_path(length(plab.locations.local_workflow_path)+2:end-1);
            figure(app.UIFigure);
            app.ProtocolTextArea.Value = ['Chosen:' newline app.protocol_name];
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value == 1

                % get date, time and other info
                app.date = string(datetime("now", 'Format', 'yyyy-MM-dd'));
                app.time = string(datetime("now", 'Format', 'HHmm'));

                if isempty(app.mouse_name)
                    app.ErrorsTextArea.Value = [app.ErrorsTextArea.Value{:} newline 'Give mouse name!!'];
                    app.ErrorsTextArea.FontColor = 'r';
                    app.StartButton.Value = 0;
                end

                if isempty(app.protocol_name)
                    app.ErrorsTextArea.Value = [app.ErrorsTextArea.Value{:} newline 'Choose a protocol!!'];
                    app.ErrorsTextArea.FontColor = 'r';
                    app.StartButton.Value = 0;
                end
                
                if ~isempty(app.mouse_name) && ~isempty(app.protocol_name) && ~isempty(app.protocol_path)
                    data_struct = struct('mouse', app.mouse_name, 'date', app.date, 'time', app.time, ...
                        'protocol_name', app.protocol_name, 'protocol_path', app.protocol_path);
                    encode_data = jsonencode(data_struct);
    
                    % Set server filename
                    server_filename = ...
                        plab.locations.make_server_filename( ...
                        data_struct.mouse,data_struct.date,data_struct.time,[data_struct.protocol_name '.bonsai']);
                    try
                        % Make server save directory
                        mkdir(fileparts(server_filename))
                    catch ME
                        app.ErrorsTextArea.Value = [app.ErrorsTextArea.Value{:} newline 'Cannot connect to server because: '  ME.message];
                    end
    
                    % send to servers
                    if app.server_stim.Connected
                        writeline(app.server_stim,encode_data);
                    end

                    if app.server_timelite.Connected
                        writeline(app.server_timelite,encode_data);
                    end

                    if app.server_mousecam.Connected
                        writeline(app.server_mousecam,encode_data);
                    end

                    if app.server_widefield.Connected
                        writeline(app.server_widefield,encode_data);
                    end
    
                    % change button
                    app.StartButton.Text = 'Stop';
                end

            else

                 % send to bonsai
                if app.server_stim.Connected
                    writeline(app.server_stim,'stop');
                end
                
            end

        end

        % Value changed function: MousenameEditField
        function MousenameEditFieldValueChanged(app, event)
            app.mouse_name =  app.MousenameEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.connections_timer);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'gray');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MousenameEditFieldLabel = uilabel(app.UIFigure);
            app.MousenameEditFieldLabel.Text = 'Mouse name';
            app.MousenameEditField = uieditfield(app.UIFigure, 'text');
            app.MousenameEditField.ValueChangedFcn = createCallbackFcn(app, @MousenameEditFieldValueChanged, true);
            app.ChooseprotocolButton = uibutton(app.UIFigure, 'state');
            app.ChooseprotocolButton.ValueChangedFcn = createCallbackFcn(app, @ChooseprotocolButtonValueChanged, true);
            app.ChooseprotocolButton.Text = 'Choose protocol';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StimLampLabel = uilabel(app.UIFigure);
            app.StimLampLabel.Text = 'Stim';
            app.StimLamp = uilamp(app.UIFigure);
            app.MousecamLampLabel = uilabel(app.UIFigure);
            app.MousecamLampLabel.Text = 'Mousecam';
            app.MousecamLamp = uilamp(app.UIFigure);
            app.TimeliteLampLabel = uilabel(app.UIFigure);
            app.TimeliteLampLabel.Text = 'Timelite';
            app.TimeliteLamp = uilamp(app.UIFigure);
            app.ErrorsTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorsTextAreaLabel.Text = 'Errors ';
            app.ErrorsTextArea = uitextarea(app.UIFigure);
            app.WidefieldLampLabel = uilabel(app.UIFigure);
            app.WidefieldLampLabel.Text = 'Widefield';
            app.WidefieldLamp = uilamp(app.UIFigure);
            app.ProtocolTextArea = uitextarea(app.UIFigure);
            app.ProtocolTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end